classdef PreisachGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PreisachModelTab                matlab.ui.container.Tab
        ModelingTabGroup                matlab.ui.container.TabGroup
        RegularTab                      matlab.ui.container.Tab
        NumberHysterons                 matlab.ui.control.EditField
        NumberHysteronsText             matlab.ui.control.Label
        ModelRegularButton              matlab.ui.control.Button
        MaxaRegular                     matlab.ui.control.EditField
        MaxaRegularText                 matlab.ui.control.Label
        PolynomialTab                   matlab.ui.container.Tab
        ParameterAText                  matlab.ui.control.Label
        ParameterA                      matlab.ui.control.EditField
        ParameterBText                  matlab.ui.control.Label
        ParameterB                      matlab.ui.control.EditField
        ParameterCText                  matlab.ui.control.Label
        ParameterC                      matlab.ui.control.EditField
        ParameterX0Text                 matlab.ui.control.Label
        ParameterX0                     matlab.ui.control.EditField
        ParameterY0Text                 matlab.ui.control.Label
        ParameterY0                     matlab.ui.control.EditField
        ModelPolynomialButton           matlab.ui.control.Button
        MaxaPolynomial                  matlab.ui.control.EditField
        MaxaPolynomialText              matlab.ui.control.Label
        NumberHysteronsPolynomial       matlab.ui.control.EditField
        NumberHysteronsPolynomialText   matlab.ui.control.Label
        SharpnessFactorPolynomial       matlab.ui.control.EditField
        SharpnessFactorPolynomialText   matlab.ui.control.Label
        StatisticalDistributionTab      matlab.ui.container.Tab
        ParameterStatisticalX0Text      matlab.ui.control.Label
        ParameterStatisticalX0          matlab.ui.control.EditField
        ParameterStatisticalY0Text      matlab.ui.control.Label
        ParameterStatisticalY0          matlab.ui.control.EditField
        ModelStatisticalButton          matlab.ui.control.Button
        StandardDeviationStatistical    matlab.ui.control.EditField
        StandardDeviationStatisticalText  matlab.ui.control.Label
        DistributionTypeDropDownLabel   matlab.ui.control.Label
        DistributionTypeDropDown        matlab.ui.control.DropDown
        NumberHysteronsStatistical      matlab.ui.control.EditField
        NumberHysteronsStatisticalText  matlab.ui.control.Label
        StandardDeviationStatisticalM   matlab.ui.control.EditField
        StandardDeviationStatisticalMText  matlab.ui.control.Label
        StandardDeviationStatisticalC   matlab.ui.control.EditField
        StandardDeviationStatisticalCText  matlab.ui.control.Label
        DerivativeArcTangentTab         matlab.ui.container.Tab
        ParameterDerivateX0Text         matlab.ui.control.Label
        SigmaDerivativeText             matlab.ui.control.Label
        SigmaDerivative                 matlab.ui.control.EditField
        ParameterDerivateX0             matlab.ui.control.EditField
        ModelDerivativeButton           matlab.ui.control.Button
        ParameterDerivateY0             matlab.ui.control.EditField
        ParameterDerivateY0Text         matlab.ui.control.Label
        EtaDerivative                   matlab.ui.control.EditField
        EtaDerivativeText               matlab.ui.control.Label
        NumberHysteronsDerivative       matlab.ui.control.EditField
        NumberHysteronsDerivativeText   matlab.ui.control.Label
        HysteronsPanel                  matlab.ui.container.Panel
        LoadModelButton                 matlab.ui.control.Button
        ModelPreisachGraph              matlab.ui.control.UIAxes
        SimulationTab                   matlab.ui.container.Tab
        uipanel1                        matlab.ui.container.Panel
        nInput                          matlab.ui.control.EditField
        nInputText                      matlab.ui.control.Label
        InputMaxaText                   matlab.ui.control.Label
        InputMaxa                       matlab.ui.control.EditField
        uipanel2                        matlab.ui.container.Panel
        slider                          matlab.ui.control.Slider
        slidertextbox                   matlab.ui.control.Label
        StartCalculate                  matlab.ui.control.Button
        textIteration                   matlab.ui.control.Label
        InputIteration                  matlab.ui.control.EditField
        textInputFrequency              matlab.ui.control.Label
        InputFrequency                  matlab.ui.control.EditField
        uipanel4                        matlab.ui.container.Panel
        text4                           matlab.ui.control.Label
        OutputText                      matlab.ui.control.EditField
        ClearGraph                      matlab.ui.control.Button
        textInputBox                    matlab.ui.control.Label
        InputBox                        matlab.ui.control.EditField
        IOGraph                         matlab.ui.control.UIAxes
        PreisachGraph                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Timer % Description
    end
    
    methods (Access = private)
        function handles = PreisachPlot(~, handles)
            %plot Preisach graph
            a0=handles.a0;
            mu=handles.mu;
            plot(handles.PreisachGraph,mu(2,:),mu(1,:),'or','MarkerSize',1, 'MarkerFaceColor','r')
            hold(handles.PreisachGraph,'on')
            axis(handles.PreisachGraph,'equal',[-a0 a0 -a0 a0]);
            plot(handles.PreisachGraph,[-a0 a0],[-a0 a0],'k')
            hold(handles.PreisachGraph,'off')
        end
        
        function handles = ModelPreisachPlot(~, handles)
            %plot Preisach graph
            a0=handles.a0;
            mu=handles.mu;
            plot(handles.ModelPreisachGraph,mu(2,:),mu(1,:),'or','MarkerSize',1, 'MarkerFaceColor','r')
            hold(handles.ModelPreisachGraph,'on')
            axis(handles.ModelPreisachGraph,'equal',[-a0 a0 -a0 a0]);
            plot(handles.ModelPreisachGraph,[-a0 a0],[-a0 a0],'k')
            hold(handles.ModelPreisachGraph,'off')
        end
        
        function handles = plotIOGraph(~, handles)
            %plots input-output graph (hysteresis graph)
            a0=handles.a0;
            if (handles.regular)
                N=handles.n*(handles.n+1)/2;
            else
                N=handles.n*handles.weight;
            end
            
            plot(handles.IOGraph,handles.uNew,handles.f,'r')
            axis(handles.IOGraph,'square',[-a0 a0 -N N]);
            hold(handles.IOGraph,'on')
            guidata(gcf,handles);
        end
        
        function handles = storeData(~, n, weight, a0, regular, f, uNew, mu, handles)
            %store data we will need in future in GUI handle
            handles.n=n;
            handles.weight=weight;
            handles.a0=a0;
            handles.regular=regular;
            handles.f=f;
            handles.uNew=uNew;
            handles.mu=mu;
        end
                
        function makeTimer(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            a0=get(handles.InputMaxa,'String');
            a0=str2double(a0);
            volt=a0;
            freq=get(handles.InputFrequency,'String');
            freq=str2double(freq);
            iter=get(handles.InputIteration,'String');        
            iter=2*str2double(iter);
             
            app.Timer = timer;
            app.Timer.StartFcn = @app.timer_Callback;
            app.Timer.TimerFcn = @app.timer_Callback;
            app.Timer.Period = 1/freq;
            app.Timer.TasksToExecute = 2*iter;
            app.Timer.ExecutionMode = 'fixedRate';
            app.Timer.UserData = struct('volt', volt, 'freq', freq, 'iter', iter, 'time', 0, 'handles', handles, 'hObject', hObject);
            start(app.Timer);
            wait(app.Timer);
            delete(app.Timer); 
        end
        
        function timer_Callback(app, obj, ~)
            handles = obj.UserData.('handles');

            value = (2*pi*obj.UserData.('freq')*(obj.UserData.('time')/(2*obj.UserData.('freq')*obj.UserData.('iter'))))-(pi/2);
            volt = obj.UserData.('volt') * sin(value);
            set(handles.slider,'Value', volt);
            
            handles.count=handles.count+1;
            handles.uNew(handles.count)=get(handles.slider,'Value');
            set(handles.InputBox,'String',handles.uNew(handles.count));
            %update model
            [handles.f(handles.count),handles.mu]=DiscretePreisach(handles.count,handles.uNew,handles.mu,handles.n,handles.weight,handles.regular);
            set(handles.OutputText,'String',handles.f(handles.count));
            %update plots
            %---plot on Preisach graph---
            %sort mu
            a0=handles.a0;
            countOn=0;
            countOff=0;
            if (handles.regular)
                N=handles.n*(handles.n+1)/2;
            else
                N=handles.n;
            end
            mu=handles.mu;
            for i=1:N
                if mu(3,i)==1
                    countOn=countOn+1;
                    muOn(1,countOn)=mu(1,i);
                    muOn(2,countOn)=mu(2,i);
                else
                    countOff=countOff+1;
                    muOff(1,countOff)=mu(1,i);
                    muOff(2,countOff)=mu(2,i);
                end
            end
            if exist('muOn','var') %if any hysterons are 'up' or 'on,' plot them
                plot(handles.PreisachGraph,muOn(2,:),muOn(1,:),'ob','MarkerSize',1, 'MarkerFaceColor','b')
                axis(handles.PreisachGraph,[-a0 a0 -a0 a0]);
                hold(handles.PreisachGraph,'on');
            end
            if exist('muOff','var') %if any hysterons are 'down' or 'off,' plot them
                plot(handles.PreisachGraph,muOff(2,:),muOff(1,:),'or','MarkerSize',1, 'MarkerFaceColor','r')
                axis(handles.PreisachGraph,[-a0 a0 -a0 a0]);
                hold(handles.PreisachGraph,'on');
            end
            %plot line to let user see the input on Preisach Graph
            if handles.uNew(handles.count)>handles.uNew(handles.count-1) %if input is increasing
                plot(handles.PreisachGraph, [-a0 a0],[handles.uNew(handles.count) handles.uNew(handles.count)],'c','LineWidth',2) %horizontal line
            else %if input decreasing
                plot(handles.PreisachGraph, [handles.uNew(handles.count) handles.uNew(handles.count)],[-a0 a0],'c','LineWidth',2) %vertical line
            end
            plot(handles.PreisachGraph, [-a0 a0],[-a0 a0],'k')
            hold(handles.PreisachGraph,'off');
            
            %plot on I/0 graph
            handles=plotIOGraph(app, handles);
            
            obj.UserData.('handles') = handles;
            obj.UserData.('time') = obj.UserData.('time') + 1;          
        end        
        
        
        function handles = clearGraph(app, handles)            
            if isfield(handles,'count') 
                hold(handles.IOGraph,'off')
                handles.uNew=handles.uNew(handles.count);%store the current input
                handles.f=handles.f(handles.count); %store the current output
                handles=plotIOGraph(app, handles);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PreisachGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % Choose default command line output for PreisachGUI
            handles.output = hObject;          
           
            % Update handles structure
            guidata(hObject, handles);                
            
        end

        % Button pushed function: ClearGraph
        function ClearGraph_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Clear IO Graph (but keep current state of system stored)
            handles = clearGraph(app, handles);  
            guidata(hObject, handles); 
        end

        % Button pushed function: StartCalculate
        function StartCalculateButtonPushed(app, event)
            makeTimer(app, event);
        end

        % Button pushed function: ModelPolynomialButton
        function ModelPolynomialButtonButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            w=waitbar(0,'Start Calculating...','WindowStyle',"modal");
            
            %read in data from GUI
            a0=get(handles.MaxaPolynomial,'String');
            a0=str2double(a0);

            regular=false;
            uNew=-a0;

            A=get(handles.ParameterA,'String');
            A=str2double(A);
            
            B=get(handles.ParameterB,'String');
            B=str2double(B);
            
            C=get(handles.ParameterC,'String');
            C=str2double(C);
            
            x0=get(handles.ParameterX0,'String');
            x0=str2double(x0);
            
            y0=get(handles.ParameterY0,'String');
            y0=str2double(y0);
            
            nHysterons=get(handles.NumberHysteronsPolynomial,'String');
            nHysterons=str2double(nHysterons);
            
            sf=get(handles.SharpnessFactorPolynomial,'String');
            sf=str2double(sf);
            
            nSpline=500;
            nc=200;
            nLevel=0;
            mu=GeneratePolynomialPreisachDistribution(a0, A, B, C, x0, y0, nSpline, nc, nLevel, nHysterons, sf, false, 0, w);
            
            % Clear IO Graph (but keep current state of system stored)
            %handles = clearGraph(app, handles);
            %guidata(hObject, handles); 
            
            [~,col]=size(mu);
            n=double(col);
            weight= nHysterons/n;
            
            f=NewDiscretePreisach(uNew,mu,n,weight,regular);
            set(handles.OutputText,'String',f);
            set(handles.InputMaxa,'String',a0);
            set(handles.slider,'Max',a0);
            set(handles.slider,'Min',-a0);
            set(handles.slider,'Value',-a0);
            set(handles.InputBox,'String',-a0);
            set(handles.nInput,'String',nHysterons);
            
            %store data we will need in future
            handles.count=1;
            handles=storeData(app,n,weight,a0,regular,f,uNew,mu,handles);
            
            %plot on Preisach graph
            handles=PreisachPlot(app, handles);
            handles=ModelPreisachPlot(app, handles);
            
            %plot on I/0 graph
            handles=plotIOGraph(app, handles);
            guidata(hObject, handles);            
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [file,path] = uigetfile('*.dat');
            if ~isequal(file,0)
               w=waitbar(0,'Reading file...','WindowStyle',"modal"); 
               [x0,y0,maxa,A,B,C,~,~,~,sf,nHysterons,mu,type] = ReadPolynomialFile(path, file, w);               
               
               % Clear IO Graph (but keep current state of system stored)
               handles = clearGraph(app, handles);
               guidata(hObject, handles); 
               
               if (type == 'P')
                   app.ModelingTabGroup.SelectedTab  = app.PolynomialTab;
                   set(handles.ParameterX0,'String',x0);
                   set(handles.ParameterY0,'String',y0);
                   set(handles.ParameterA,'String',A);
                   set(handles.ParameterB,'String',B);
                   set(handles.ParameterC,'String',C);
                   set(handles.MaxaPolynomial,'String',maxa);
                   set(handles.SharpnessFactorPolynomial,'String',sf);
                   set(handles.NumberHysteronsPolynomial,'String',nHysterons);
                   
                   weight=1;
                   a0 = maxa;
                   regular=false;
                   uNew=-a0;
                   
                   [~,col]=size(mu);
                   n=double(col);
                   
                   f=NewDiscretePreisach(uNew,mu,n,regular);
                   set(handles.OutputText,'String',f);
                   set(handles.InputMaxa,'String',a0);
                   set(handles.slider,'Max',a0);
                   set(handles.slider,'Min',-a0);
                   set(handles.slider,'Value',-a0);
                   set(handles.InputBox,'String',-a0);
                   set(handles.nInput,'String',n);
            
                   %store data we will need in future
                   handles.count=1;
                   handles=storeData(app,n,weight,a0,regular,f,uNew,mu,handles);
            
                   %plot on Preisach graph
                   handles=PreisachPlot(app, handles);
                   handles=ModelPreisachPlot(app, handles);
            
                   %plot on I/0 graph
                   handles=plotIOGraph(app, handles);
                   guidata(hObject, handles);
               end
            end
        end

        % Button pushed function: ModelRegularButton
        function ModelRegularButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
           
            %read in data from GUI
            n=get(handles.NumberHysterons,'String');
            n=str2double(n);
            a0=get(handles.MaxaRegular,'String');
            a0=str2double(a0);

            regular=true;
            uNew=-a0;
            
            mu=generateMu(n,a0,regular);
            f=NewDiscretePreisach(uNew,mu,n,1,regular);
            
            set(handles.OutputText,'String',f);
            set(handles.InputMaxa,'String',a0);
            set(handles.slider,'Max',a0);
            set(handles.slider,'Min',-a0);
            set(handles.slider,'Value',-a0);
            set(handles.InputBox,'String',-a0);
            set(handles.nInput,'String',n);
            
            %store data we will need in future
            handles.count=1;
            handles=storeData(app,n,1,a0,regular,f,uNew,mu,handles);
    
            %plot on Preisach graph
            handles=PreisachPlot(app, handles);
            handles=ModelPreisachPlot(app, handles);
    
            %plot on I/0 graph
            handles=plotIOGraph(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: ModelStatisticalButton
        function ModelStatisticalButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            w=waitbar(0,'Start Calculating...','WindowStyle',"modal");
            
            %read in data from GUI
            a0=get(handles.MaxaPolynomial,'String');
            a0=str2double(a0);

            regular=false;
            uNew=-a0;

            sigmaM=get(handles.StandardDeviationStatisticalM,'String');
            sigmaM=str2double(sigmaM);

            sigmaC=get(handles.StandardDeviationStatisticalC,'String');
            sigmaC=str2double(sigmaC);
            
            sigma=get(handles.StandardDeviationStatistical,'String');
            sigma=str2double(sigma);

            x0=get(handles.ParameterStatisticalX0,'String');
            x0=str2double(x0);
            
            y0=get(handles.ParameterStatisticalY0,'String');
            y0=str2double(y0);
            
            nHysterons=get(handles.NumberHysteronsStatistical,'String');
            nHysterons=str2double(nHysterons);
                        
            nSpline=500;
            nc=100;

            dt=app.DistributionTypeDropDown.Value;
            
            if (dt=="Gaussian")
                mu=GenerateGaussianPreisachDistribution(a0, sigma, x0, y0, nSpline, nc, 0, w);
            elseif (dt=="Cauchy")
                mu=GenerateCauchyPreisachDistribution(a0, sigma, x0, y0, nSpline, nc, 0, w);
            elseif (dt=="Gaussian-Gaussian")
                mu=GenerateGaussianGaussianPreisachDistribution(a0, sigmaM, sigmaC, x0, y0, nSpline, nc, 0, w);
            elseif (dt=="Gaussian-Cauchy")
                mu=GenerateGaussianCauchyPreisachDistribution(a0, sigmaM, sigmaC, x0, y0, nSpline, nc, 0, w);
            elseif (dt=="Cauchy-Cauchy")
                mu=GenerateCauchyCauchyPreisachDistribution(a0, sigmaM, sigmaC, x0, y0, nSpline, nc, 0, w);
            elseif (dt=="Cauchy-Gaussian")
                mu=GenerateCauchyGaussianPreisachDistribution(a0, sigmaM, sigmaC, x0, y0, nSpline, nc, 0, w);
            elseif (dt=="Gaussian-Lognormal")
                mu=GenerateGaussianLogNormalPreisachDistribution(a0, sigmaM, sigmaC, x0, y0, nSpline, nc, 0, w);
            elseif (dt=="Cauchy-Lognormal")
                mu=GenerateCauchyLogNormalPreisachDistribution(a0, sigmaM, sigmaC, x0, y0, nSpline, nc, 0, w);
            end
            
            % Clear IO Graph (but keep current state of system stored)
            %handles = clearGraph(app, handles);
            %guidata(hObject, handles); 
            
            [~,col]=size(mu);
            n=double(col);
            weight= nHysterons/n;
            
            f=NewDiscretePreisach(uNew,mu,n,weight,regular);
            set(handles.OutputText,'String',f);
            set(handles.InputMaxa,'String',a0);
            set(handles.slider,'Max',a0);
            set(handles.slider,'Min',-a0);
            set(handles.slider,'Value',-a0);
            set(handles.InputBox,'String',-a0);
            set(handles.nInput,'String',nHysterons);
            
            %store data we will need in future
            handles.count=1;
            handles=storeData(app,n,weight,a0,regular,f,uNew,mu,handles);
            
            %plot on Preisach graph
            handles=PreisachPlot(app, handles);
            handles=ModelPreisachPlot(app, handles);
            
            %plot on I/0 graph
            handles=plotIOGraph(app, handles);
            guidata(hObject, handles);                        
        end

        % Button pushed function: ModelDerivativeButton
        function ModelDerivativeButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            w=waitbar(0,'Start Calculating...','WindowStyle',"modal");
            
            %read in data from GUI
            a0=get(handles.MaxaPolynomial,'String');
            a0=str2double(a0);

            regular=false;
            uNew=-a0;

            sigma=get(handles.SigmaDerivative,'String');
            sigma=str2double(sigma);
                                    
            eta=get(handles.EtaDerivative,'String');
            eta=str2double(eta);

            x0=get(handles.ParameterDerivateX0,'String');
            x0=str2double(x0);
            
            y0=get(handles.ParameterDerivateY0,'String');
            y0=str2double(y0);
            
            nHysterons=get(handles.NumberHysteronsDerivative,'String');
            nHysterons=str2double(nHysterons);
                        
            nSpline=500;
            nc=100;
            
            mu=GenerateDATPreisachDistribution(a0, sigma, eta, x0, y0, nSpline, nc, 0, w);
            
            % Clear IO Graph (but keep current state of system stored)
            %handles = clearGraph(app, handles);
            %guidata(hObject, handles); 
            
            [~,col]=size(mu);
            n=double(col);
            weight= nHysterons/n;
                        
            f=NewDiscretePreisach(uNew,mu,n,weight,regular);
            set(handles.OutputText,'String',f);
            set(handles.InputMaxa,'String',a0);
            set(handles.slider,'Max',a0);
            set(handles.slider,'Min',-a0);
            set(handles.slider,'Value',-a0);
            set(handles.InputBox,'String',-a0);
            set(handles.nInput,'String',nHysterons);
            
            %store data we will need in future
            handles.count=1;
            handles=storeData(app,n,weight,a0,regular,f,uNew,mu,handles);
            
            %plot on Preisach graph
            handles=PreisachPlot(app, handles);
            handles=ModelPreisachPlot(app, handles);
            
            %plot on I/0 graph
            handles=plotIOGraph(app, handles);
            guidata(hObject, handles);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'PreisachGUI';
            app.figure1.Tag = 'figure1';
            app.TabGroup = uitabgroup(app.figure1);
            app.PreisachModelTab = uitab(app.TabGroup);
            app.PreisachModelTab.Title = 'Preisach Model';
            app.ModelingTabGroup = uitabgroup(app.PreisachModelTab);
            app.RegularTab = uitab(app.ModelingTabGroup);
            app.RegularTab.Title = 'Regular';
            app.NumberHysterons = uieditfield(app.RegularTab, 'text');
            app.NumberHysterons.Tag = 'NumberHysterons';
            app.NumberHysterons.Value = '10';
            app.NumberHysteronsText = uilabel(app.RegularTab);
            app.NumberHysteronsText.Tag = 'NumberHysteronsText';
            app.NumberHysteronsText.Text = 'Number of hysterons';
            app.ModelRegularButton = uibutton(app.RegularTab, 'push');
            app.ModelRegularButton.ButtonPushedFcn = createCallbackFcn(app, @ModelRegularButtonPushed, true);
            app.ModelRegularButton.Tag = 'ModelRegularButton';
            app.ModelRegularButton.Text = 'Get Model';
            app.MaxaRegular = uieditfield(app.RegularTab, 'text');
            app.MaxaRegular.Tag = 'MaxaRegular';
            app.MaxaRegular.Value = '5';
            app.MaxaRegularText = uilabel(app.RegularTab);
            app.MaxaRegularText.Tag = 'MaxaRegularText';
            app.MaxaRegularText.Text = 'Max a';
            app.PolynomialTab = uitab(app.ModelingTabGroup);
            app.PolynomialTab.Title = 'Polynomial';
            app.ParameterAText = uilabel(app.PolynomialTab);
            app.ParameterAText.Tag = 'ParameterAText';
            app.ParameterAText.Text = 'A';
            app.ParameterA = uieditfield(app.PolynomialTab, 'text');
            app.ParameterA.Tag = 'ParameterA';
            app.ParameterA.Value = '1000';
            app.ParameterBText = uilabel(app.PolynomialTab);
            app.ParameterBText.Tag = 'ParameterBText';
            app.ParameterBText.Text = 'B';
            app.ParameterB = uieditfield(app.PolynomialTab, 'text');
            app.ParameterB.Tag = 'ParameterB';
            app.ParameterB.Value = '1000';
            app.ParameterCText = uilabel(app.PolynomialTab);
            app.ParameterCText.Tag = 'ParameterCText';
            app.ParameterCText.Text = 'C';
            app.ParameterC = uieditfield(app.PolynomialTab, 'text');
            app.ParameterC.Tag = 'ParameterC';
            app.ParameterC.Value = '2';
            app.ParameterX0Text = uilabel(app.PolynomialTab);
            app.ParameterX0Text.Tag = 'ParameterX0Text';
            app.ParameterX0Text.Text = 'X0';
            app.ParameterX0 = uieditfield(app.PolynomialTab, 'text');
            app.ParameterX0.Tag = 'ParameterX0';
            app.ParameterX0.Value = '-2.5';
            app.ParameterY0Text = uilabel(app.PolynomialTab);
            app.ParameterY0Text.Tag = 'ParameterY0Text';
            app.ParameterY0Text.Text = 'Y0';
            app.ParameterY0 = uieditfield(app.PolynomialTab, 'text');
            app.ParameterY0.Tag = 'ParameterY0';
            app.ParameterY0.Value = '2.5';
            app.ModelPolynomialButton = uibutton(app.PolynomialTab, 'push');
            app.ModelPolynomialButton.ButtonPushedFcn = createCallbackFcn(app, @ModelPolynomialButtonButtonPushed, true);
            app.ModelPolynomialButton.Tag = 'ModelPolynomialButton';
            app.ModelPolynomialButton.Text = 'Get Model';
            app.MaxaPolynomial = uieditfield(app.PolynomialTab, 'text');
            app.MaxaPolynomial.Tag = 'MaxaPolynomial';
            app.MaxaPolynomial.Value = '5';
            app.MaxaPolynomialText = uilabel(app.PolynomialTab);
            app.MaxaPolynomialText.Tag = 'MaxaPolynomialText';
            app.MaxaPolynomialText.Text = 'Max a';
            app.NumberHysteronsPolynomial = uieditfield(app.PolynomialTab, 'text');
            app.NumberHysteronsPolynomial.Tag = 'NumberHysteronsPolynomial';
            app.NumberHysteronsPolynomial.Value = '50000';
            app.NumberHysteronsPolynomialText = uilabel(app.PolynomialTab);
            app.NumberHysteronsPolynomialText.Tag = 'NumberHysteronsPolynomialText';
            app.NumberHysteronsPolynomialText.Text = 'Number of hysterons';
            app.SharpnessFactorPolynomial = uieditfield(app.PolynomialTab, 'text');
            app.SharpnessFactorPolynomial.Tag = 'SharpnessFactorPolynomial';
            app.SharpnessFactorPolynomial.Value = '1';
            app.SharpnessFactorPolynomialText = uilabel(app.PolynomialTab);
            app.SharpnessFactorPolynomialText.Tag = 'SharpnessFactorPolynomialText';
            app.SharpnessFactorPolynomialText.Text = 'Sharpness Factor';
            app.StatisticalDistributionTab = uitab(app.ModelingTabGroup);
            app.StatisticalDistributionTab.Title = 'Statistical Distribution';
            app.ParameterStatisticalX0Text = uilabel(app.StatisticalDistributionTab);
            app.ParameterStatisticalX0Text.Tag = 'ParameterStatisticalX0Text';
            app.ParameterStatisticalX0Text.Text = 'X0';
            app.ParameterStatisticalX0 = uieditfield(app.StatisticalDistributionTab, 'text');
            app.ParameterStatisticalX0.Tag = 'ParameterStatisticalX0';
            app.ParameterStatisticalX0.Value = '-2.5';
            app.ParameterStatisticalY0Text = uilabel(app.StatisticalDistributionTab);
            app.ParameterStatisticalY0Text.Tag = 'ParameterStatisticalY0Text';
            app.ParameterStatisticalY0Text.Text = 'Y0';
            app.ParameterStatisticalY0 = uieditfield(app.StatisticalDistributionTab, 'text');
            app.ParameterStatisticalY0.Tag = 'ParameterStatisticalY0';
            app.ParameterStatisticalY0.Value = '2.5';
            app.ModelStatisticalButton = uibutton(app.StatisticalDistributionTab, 'push');
            app.ModelStatisticalButton.ButtonPushedFcn = createCallbackFcn(app, @ModelStatisticalButtonPushed, true);
            app.ModelStatisticalButton.Tag = 'ModelStatisticalButton';
            app.ModelStatisticalButton.Text = 'Get Model';
            app.StandardDeviationStatistical = uieditfield(app.StatisticalDistributionTab, 'text');
            app.StandardDeviationStatistical.Tag = 'StandardDeviationStatistical';
            app.StandardDeviationStatistical.Value = '2';
            app.StandardDeviationStatisticalText = uilabel(app.StatisticalDistributionTab);
            app.StandardDeviationStatisticalText.Tag = 'StandardDeviationStatisticalText';
            app.StandardDeviationStatisticalText.Text = 'Standard Deviation';
            app.DistributionTypeDropDownLabel = uilabel(app.StatisticalDistributionTab);
            app.DistributionTypeDropDownLabel.Text = 'Distribution Type';
            app.DistributionTypeDropDown = uidropdown(app.StatisticalDistributionTab);
            app.DistributionTypeDropDown.Items = {'Gaussian', 'Cauchy', 'Gaussian-Gaussian', 'Gaussian-Cauchy', 'Cauchy-Cauchy', 'Cauchy-Gaussian', 'Gaussian-Lognormal', 'Cauchy-Lognormal'};
            app.DistributionTypeDropDown.Tag = 'DistributionTypeDropDown';
            app.DistributionTypeDropDown.Value = 'Gaussian';
            app.NumberHysteronsStatistical = uieditfield(app.StatisticalDistributionTab, 'text');
            app.NumberHysteronsStatistical.Tag = 'NumberHysteronsStatistical';
            app.NumberHysteronsStatistical.Value = '50000';
            app.NumberHysteronsStatisticalText = uilabel(app.StatisticalDistributionTab);
            app.NumberHysteronsStatisticalText.Tag = 'NumberHysteronsStatisticalText';
            app.NumberHysteronsStatisticalText.Text = 'Number of hysterons';
            app.StandardDeviationStatisticalM = uieditfield(app.StatisticalDistributionTab, 'text');
            app.StandardDeviationStatisticalM.Tag = 'StandardDeviationStatisticalM';
            app.StandardDeviationStatisticalM.Value = '2';
            app.StandardDeviationStatisticalMText = uilabel(app.StatisticalDistributionTab);
            app.StandardDeviationStatisticalMText.Tag = 'StandardDeviationStatisticalMText';
            app.StandardDeviationStatisticalMText.Text = 'Standard Deviation (M)';
            app.StandardDeviationStatisticalC = uieditfield(app.StatisticalDistributionTab, 'text');
            app.StandardDeviationStatisticalC.Tag = 'StandardDeviationStatisticalC';
            app.StandardDeviationStatisticalC.Value = '2';
            app.StandardDeviationStatisticalCText = uilabel(app.StatisticalDistributionTab);
            app.StandardDeviationStatisticalCText.Tag = 'StandardDeviationStatisticalCText';
            app.StandardDeviationStatisticalCText.Text = 'Standard Deviation (C)';
            app.DerivativeArcTangentTab = uitab(app.ModelingTabGroup);
            app.DerivativeArcTangentTab.Title = 'Derivative Arc Tangent';
            app.ParameterDerivateX0Text = uilabel(app.DerivativeArcTangentTab);
            app.ParameterDerivateX0Text.Tag = 'ParameterDerivateX0Text';
            app.ParameterDerivateX0Text.Text = 'X0';
            app.SigmaDerivativeText = uilabel(app.DerivativeArcTangentTab);
            app.SigmaDerivativeText.Tag = 'SigmaDerivativeText';
            app.SigmaDerivativeText.Text = 'Sigma';
            app.SigmaDerivative = uieditfield(app.DerivativeArcTangentTab, 'text');
            app.SigmaDerivative.Tag = 'SigmaDerivative';
            app.SigmaDerivative.Value = '2';
            app.ParameterDerivateX0 = uieditfield(app.DerivativeArcTangentTab, 'text');
            app.ParameterDerivateX0.Tag = 'ParameterDerivateX0';
            app.ParameterDerivateX0.Value = '-2.5';
            app.ModelDerivativeButton = uibutton(app.DerivativeArcTangentTab, 'push');
            app.ModelDerivativeButton.ButtonPushedFcn = createCallbackFcn(app, @ModelDerivativeButtonPushed, true);
            app.ModelDerivativeButton.Tag = 'ModelDerivativeButton';
            app.ModelDerivativeButton.Text = 'Get Model';
            app.ParameterDerivateY0 = uieditfield(app.DerivativeArcTangentTab, 'text');
            app.ParameterDerivateY0.Tag = 'ParameterDerivateY0';
            app.ParameterDerivateY0.Value = '2.5';
            app.ParameterDerivateY0Text = uilabel(app.DerivativeArcTangentTab);
            app.ParameterDerivateY0Text.Tag = 'ParameterDerivateY0Text';
            app.ParameterDerivateY0Text.Text = 'Y0';
            app.EtaDerivative = uieditfield(app.DerivativeArcTangentTab, 'text');
            app.EtaDerivative.Tag = 'EtaDerivative';
            app.EtaDerivative.Value = '0.5';
            app.EtaDerivativeText = uilabel(app.DerivativeArcTangentTab);
            app.EtaDerivativeText.Tag = 'EtaDerivativeText';
            app.EtaDerivativeText.Text = 'Eta';
            app.NumberHysteronsDerivative = uieditfield(app.DerivativeArcTangentTab, 'text');
            app.NumberHysteronsDerivative.Tag = 'NumberHysteronsDerivative';
            app.NumberHysteronsDerivative.Value = '50000';
            app.NumberHysteronsDerivativeText = uilabel(app.DerivativeArcTangentTab);
            app.NumberHysteronsDerivativeText.Tag = 'NumberHysteronsDerivativeText';
            app.NumberHysteronsDerivativeText.Text = 'Number of hysterons';
            app.HysteronsPanel = uipanel(app.PreisachModelTab);
            app.HysteronsPanel.Title = 'Hysterons';
            app.LoadModelButton = uibutton(app.HysteronsPanel, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Tag = 'LoadModelButton';
            app.LoadModelButton.Text = 'Load Model';
            app.ModelPreisachGraph = uiaxes(app.HysteronsPanel);
            app.ModelPreisachGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ModelPreisachGraph.Tag = 'ModelPreisachGraph';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.uipanel1 = uipanel(app.SimulationTab);
            app.uipanel1.Tag = 'uipanel1';
            app.nInput = uieditfield(app.uipanel1, 'text');
            app.nInput.Tag = 'nInput';
            app.nInput.Value = '5';
            app.nInputText = uilabel(app.uipanel1);
            app.nInputText.Tag = 'nInputText';
            app.nInputText.Text = 'Number of hysterons';
            app.InputMaxaText = uilabel(app.uipanel1);
            app.InputMaxaText.Tag = 'InputMaxaText';
            app.InputMaxaText.Text = 'Max a';
            app.InputMaxa = uieditfield(app.uipanel1, 'text');
            app.InputMaxa.Tag = 'InputMaxa';
            app.InputMaxa.Value = '5';
            app.uipanel2 = uipanel(app.SimulationTab);
            app.uipanel2.Tag = 'uipanel2';
            app.slider = uislider(app.uipanel2);
            app.slider.Limits = [0 1];
            app.slider.MajorTicks = [];
            app.slider.MinorTicks = [];
            app.slider.Tag = 'slider';
            app.slidertextbox = uilabel(app.uipanel2);
            app.slidertextbox.Tag = 'slidertextbox';
            app.slidertextbox.Text = 'Input u(t)';
            app.StartCalculate = uibutton(app.uipanel2, 'push');
            app.StartCalculate.ButtonPushedFcn = createCallbackFcn(app, @StartCalculateButtonPushed, true);
            app.StartCalculate.Tag = 'ClearGraph';
            app.StartCalculate.Text = 'Start';
            app.textIteration = uilabel(app.uipanel2);
            app.textIteration.Tag = 'text6';
            app.textIteration.Text = 'Iteration';
            app.InputIteration = uieditfield(app.uipanel2, 'text');
            app.InputIteration.Tag = 'InputIteration';
            app.InputIteration.Value = '100';
            app.textInputFrequency = uilabel(app.uipanel2);
            app.textInputFrequency.Tag = 'text5';
            app.textInputFrequency.Text = 'Frequency';
            app.InputFrequency = uieditfield(app.uipanel2, 'text');
            app.InputFrequency.Tag = 'InputFrequency';
            app.InputFrequency.Value = '5';
            app.uipanel4 = uipanel(app.SimulationTab);
            app.uipanel4.Tag = 'uipanel4';
            app.text4 = uilabel(app.uipanel4);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Output:';
            app.OutputText = uieditfield(app.uipanel4, 'text');
            app.OutputText.Tag = 'OutputText';
            app.OutputText.Value = '0';
            app.ClearGraph = uibutton(app.uipanel4, 'push');
            app.ClearGraph.ButtonPushedFcn = createCallbackFcn(app, @ClearGraph_pushbutton_Callback, true);
            app.ClearGraph.Tag = 'ClearGraph';
            app.ClearGraph.Text = 'Clear Graph';
            app.textInputBox = uilabel(app.uipanel4);
            app.textInputBox.Tag = 'text6';
            app.textInputBox.Text = 'Input:';
            app.InputBox = uieditfield(app.uipanel4, 'text');
            app.InputBox.Tag = 'InputBox';
            app.InputBox.Value = '0';
            app.IOGraph = uiaxes(app.SimulationTab);
            app.IOGraph.XLim = [0 1];
            app.IOGraph.Tag = 'IOGraph';
            app.PreisachGraph = uiaxes(app.SimulationTab);
            app.PreisachGraph.Tag = 'PreisachGraph';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreisachGUI_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)PreisachGUI_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end